classdef BigMuffApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SaveAudioButton       matlab.ui.control.Button
        Playing________Label  matlab.ui.control.Label
        AudioName             matlab.ui.control.Label
        StopAudioButton       matlab.ui.control.Button
        PlayAudioButton       matlab.ui.control.Button
        LoadAudioButton       matlab.ui.control.Button
        SUSTAINLabel          matlab.ui.control.Label
        TONELabel             matlab.ui.control.Label
        VOLUMELabel           matlab.ui.control.Label
        Sustain               matlab.ui.control.Knob
        Tone                  matlab.ui.control.Knob
        Volume                matlab.ui.control.Knob
        Lamp                  matlab.ui.control.Lamp
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        Audio_input % Description
        SamplingFreq % Description
        sustain
        tone
        gain
        low_cutoff
        high_cutoff
        Audio_loadfile_name % Description
        Audio_loadfile_ext % Description
        Audio_loadpath % Description
        Audio_loadfile % Description
        Audio_savefile % Description
        Audio_savepath % Description
        Audio_write % Description
        Audio_volume
        Audio_play % Description
        Gain_output_flag % Description
        Audio_playback_object % Description
        Audio_processed % Description
        Audio_weighted
        clipOut
        G_low
        G_high
        b_low
        a_low
        b_high
        a_high
        h_low % filter response for low shelf
        h_high % filter response for high shelf
        h_omega % filter response angular frequency values
        h_prod % product of all filter responses
        Audio_pause_time % audio sample packet number where playback paused
    end
    
    methods (Access = private)
        
        function [b,a] = ShelvingFilter(app,type,G)
            
            if abs(G) <= 0.01
                b = 1;
                a = 1;
            else
                if (strcmp(type,'Low'))
                    K = tan((pi*app.low_cutoff)/app.SamplingFreq);
                else
                    K = tan((pi*app.high_cutoff)/app.SamplingFreq);
                end
                V0 = 10^(G/20);
                if (G>=0 && strcmp(type,'Low'))
                % Low Boost
                    b0 = (1+sqrt(2*V0)*K+V0*K^2)/(1+sqrt(2)*K+K^2);
                    b1 = 2*(V0*K^2-1)/(1+sqrt(2)*K+K^2);
                    b2 = (1-sqrt(2*V0)*K+V0*K^2)/(1+sqrt(2)*K+K^2);
                    a1 = 2*(K^2-1)/(1+sqrt(2)*K+K^2);
                    a2 = (1-sqrt(2)*K+K^2)/(1+sqrt(2)*K+K^2);
    
                elseif (G<0 && strcmp(type,'Low'))
                % Low Cut
                    b0 = V0*(1+sqrt(2)*K+K^2)/(V0+sqrt(2*V0)*K+K^2);
                    b1 = 2*V0*(K^2-1)/(V0+sqrt(2*V0)*K+K^2);
                    b2 = V0*(1-sqrt(2)*K+K^2)/(V0+sqrt(2*V0)*K+K^2);
                    a1 = 2*(K^2-V0)/(V0+sqrt(2*V0)*K+K^2);
                    a2 = (V0-sqrt(2*V0)*K+K^2)/(V0+sqrt(2*V0)*K+K^2);
   
                elseif (G>=0 && strcmp(type,'High'))
                % High Boost
                    b0 = (V0+sqrt(2*V0)*K+K^2)/(1+sqrt(2)*K+K^2);
                    b1 = 2*(K^2-V0)/(1+sqrt(2)*K+K^2);
                    b2 = (V0-sqrt(2*V0)*K+K^2)/(1+sqrt(2)*K+K^2);
                    a1 = 2*(K^2-1)/(1+sqrt(2)*K+K^2);
                    a2 = (1-sqrt(2)*K+K^2)/(1+sqrt(2)*K+K^2);
    
                else
                %High Cut
                    b0 = V0*(1+sqrt(2)*K+K^2)/(1+sqrt(2*V0)*K+V0*K^2);
                    b1 = 2*V0*(K^2-1)/(1+sqrt(2*V0)*K+V0*K^2);
                    b2 = V0*(1-sqrt(2)*K+K^2)/(1+sqrt(2*V0)*K+V0*K^2);
                    a1 = 2*(V0*K^2-1)/(1+sqrt(2*V0)*K+V0*K^2);
                    a2 = (1-sqrt(2*V0)*K+V0*K^2)/(1+sqrt(2*V0)*K+V0*K^2);

                end
                a = [1, a1, a2];
                b = [b0, b1, b2];
            end  
        end
        function [ out ] = softClip(~,in,a)
        N = length(in);
        out = zeros(N,1);
        for n = 1:N
        out(n,1) = in(n,1) - a*(1/3)*in(n,1)^3;
        end
        end
        function [out] = halfwaveRectification(~,in)

        N = length(in);
        out = zeros(N,1);
        for n = 1:N
           
            if in(n,1) >= 0 
                % If positive, assign input to output
                out(n,1) = in(n,1);
            else
                % If negative, set output to zero
                out(n,1) = 0;
                
            end
            
        end
        end

        function [out] = hardClip(~,in,thresh)
        %HARDCLIP Summary of this function goes here
        % Detailed explanation goes here
        N = length(in);
        out = zeros(N,1);
        for n = 1:N
         if in(n,1) >= thresh
         % if true, assign output = thresh
         out(n,1) = thresh;
         elseif in(n,1)<= -thresh
         % if true, set output = -thresh
         out(n,1) = -thresh;
         else
         out(n,1) = in(n,1);
         end
        end
        end


        function applyDistortion(app)
            sustainValue = app.sustain;
            inCopy = sustainValue .*app.Audio_input;
            g1 = 1;
            g2 = 0.4;
            softclip = softClip(app,inCopy, g1);
            waverect = halfwaveRectification(app,softclip);
            clipped = hardClip(app,waverect, g2);
            app.clipOut = app.Audio_input+clipped;
        end

        function applyFilter(app)
            [app.b_low,app.a_low] = ShelvingFilter(app,'Low', app.G_low);
            [app.h_low,app.h_omega] = freqz(app.b_low,app.a_low,2048);

            [app.b_high,app.a_high] = ShelvingFilter(app,'High', app.G_high);
            [app.h_high,app.h_omega] = freqz(app.b_high,app.a_high,2048);
            h_eq = app.h_low.*app.h_high;
            if h_eq(:,1) == ones(size(app.h_omega))
                b = 1;
                a = 1;
            else
                [b,a]=invfreqz(h_eq,app.h_omega,2,2);
            end
            app.Audio_processed = filter(b,a,app.clipOut);
        end

        function mgain(app)
        %GAIN Outputs a signal with gain control 
        app.Audio_play = app.Audio_volume * app.gain;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.low_cutoff = 1000;
            app.high_cutoff = 1000;
            app.SamplingFreq = 44100;
            [~,app.h_omega] = freqz(1,1,2048);
            app.h_prod = ones(size(app.h_omega));
            app.h_low = ones(size(app.h_omega));
            app.h_high = ones(size(app.h_omega));
            app.Tone.Value = 0.5;
            app.G_high = 6;
            app.G_low = 6;
            app.Sustain.Value = 0.5;
            app.sustain = 0.5;
            app.gain = 0.5;
            app.Volume.Value = 0.5;
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
             [app.Audio_loadfile,app.Audio_loadpath] = uigetfile({'*.wav;*.ogg;*.oga;*.flac;*.au;*.aiff;*.aif;*.aifc;*.mp3;*.m4a;*.mp4','All Audio Files (*.wav,*.ogg,*.oga,*.flac,*.au,*.aiff,*.aif,*.aifc,*.mp3,*.m4a,*.mp4)';...
                '*.wav','WAVE File (*.wav)';'*.ogg;*.oga','OGG File (*.ogg,*.oga)';'*.flac','FLAC File (*.flac)';'*.au','AU File (*.au)';'*.aiff;*.aif',...
                 'AIFF File (*.aiff,*.aif)';'*.aifc','AIFC File (*.aifc)';'*.mp3','MP3 File (*.mp3)';...
                 '*.m4a;*.mp4','MPEG-4 AAC (*.m4a,*.mp4)'},'Load Audio');
             figure(app.UIFigure); %Workaround to stop the Plugin closing when opening a file
             if ~isequal(app.Audio_loadfile,0)
                [app.Audio_input,app.SamplingFreq] = audioread([app.Audio_loadpath,app.Audio_loadfile]);
                app.Audio_processed = app.Audio_input;
                app.h_low = ones(size(app.h_omega));
                app.h_high = ones(size(app.h_omega));
                % Loaded Audio File Name
                [~,app.Audio_loadfile_name,app.Audio_loadfile_ext] = fileparts([app.Audio_loadpath,app.Audio_loadfile]);
                app.AudioName.Text = [app.Audio_loadfile_name, app.Audio_loadfile_ext];
            end
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
             if isempty(app.Audio_input)
                uialert(app.UIFigure,'No Audio File to Play','File Not Found')
             else
                 applyDistortion(app);
                 applyFilter(app);
                 
                 app.Audio_volume = app.Audio_processed./max(max(abs(app.Audio_processed)));
                 mgain(app);
                 app.Audio_playback_object = audioplayer(app.Audio_play,app.SamplingFreq);
                 play(app.Audio_playback_object)
                 app.Lamp.Color = [0 1 0];

                 N = length(app.Audio_processed);
                 n = N/app.SamplingFreq;
                 pause(n);
                 app.Lamp.Color = [1 0 0];
             end
        end

        % Value changed function: Volume
        function VolumeValueChanged(app, event)
            value = app.Volume.Value;
            app.gain = value;
        end

        % Value changed function: Sustain
        function SustainValueChanged(app, event)
            value = app.Sustain.Value;
            app.sustain = value;
        end

        % Value changed function: Tone
        function ToneValueChanged(app, event)
            value = app.Tone.Value;
            if app.Tone.Value == 0.5
                app.G_low = 7.5;
                app.G_high = 7.5;
            else if app.Tone.Value < 0.5
                    multiplier = mod(app.Tone.Value,.26);
                    app.G_low =13.5 - (multiplier * 0.52);
                    app.G_high = multiplier * 0.52;
            else
                    multiplier = mod(app.Tone.Value,.26);
                    app.G_high =13.5 - (multiplier * 0.52);
                    app.G_low = multiplier * 0.52;
            end
            end

        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            if isempty(app.Audio_input)
                uialert(app.UIFigure,'No Audio File','File Not Found')
            elseif isempty(app.Audio_playback_object)
                uialert(app.UIFigure,'No Sound Playing','File Not Found')
            else
                stop(app.Audio_playback_object)
                app.Lamp.Color = [1 0 0];
            end
        end

        % Button pushed function: SaveAudioButton
        function SaveAudioButtonPushed(app, event)
            if isempty(app.Audio_input)
                uialert(app.UIFigure,'No Audio File to Save','File Not Found')
            else
                [app.Audio_savefile,app.Audio_savepath] = uiputfile({'*.wav','WAVE File (*.wav)';'*.ogg','OGG File (*.ogg)'; ...
                        '*.oga','OGG File (*.oga)';'*.flac','FLAC File (*.flac)';'*.m4a','MPEG-4 AAC (*.m4a)';...
                        '*.mp4','MPEG-4 AAC (*.mp4)'},'Save Audio', [app.Audio_loadfile_name,'_',...
                        'Fuzzed']);
            applyDistortion(app);
            applyFilter(app);
                 
            app.Audio_volume = app.Audio_processed./max(max(abs(app.Audio_processed)));
            mgain(app);
            app.Audio_write = app.Audio_play;
            audiowrite([app.Audio_savepath,app.Audio_savefile],app.Audio_write,app.SamplingFreq)
            figure(app.UIFigure); %Workaround to stop the Plugin closing when saving a file (MATLAB tries to focus on new file
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Background.png');
            app.Lamp = uilamp(app.UIFigure);
            app.Volume = uiknob(app.UIFigure, 'continuous');
            app.Volume.Limits = [0 1];
            app.Volume.ValueChangedFcn = createCallbackFcn(app, @VolumeValueChanged, true);
            app.Tone = uiknob(app.UIFigure, 'continuous');
            app.Tone.Limits = [0 1];
            app.Tone.ValueChangedFcn = createCallbackFcn(app, @ToneValueChanged, true);
            app.Sustain = uiknob(app.UIFigure, 'continuous');
            app.Sustain.Limits = [0 1];
            app.Sustain.ValueChangedFcn = createCallbackFcn(app, @SustainValueChanged, true);
            app.VOLUMELabel = uilabel(app.UIFigure);
            app.VOLUMELabel.Text = 'VOLUME';
            app.TONELabel = uilabel(app.UIFigure);
            app.TONELabel.Text = 'TONE';
            app.SUSTAINLabel = uilabel(app.UIFigure);
            app.SUSTAINLabel.Text = 'SUSTAIN';
            app.LoadAudioButton = uibutton(app.UIFigure, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.StopAudioButton = uibutton(app.UIFigure, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.AudioName = uilabel(app.UIFigure);
            app.AudioName.Text = '';
            app.Playing________Label = uilabel(app.UIFigure);
            app.Playing________Label.Text = '________Playing________';
            app.SaveAudioButton = uibutton(app.UIFigure, 'push');
            app.SaveAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioButtonPushed, true);
            app.SaveAudioButton.Text = 'Save Audio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BigMuffApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end